classdef TIPSconfig_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_OpenTIPS                   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel_Settings                matlab.ui.container.Panel
        Grid_Settings                 matlab.ui.container.GridLayout
        ROIsettingPanel               matlab.ui.container.Panel
        Button_ROIDisplay             matlab.ui.control.Button
        Button_ROIMinus               matlab.ui.control.Button
        Button_ROIAdd                 matlab.ui.control.Button
        UITable_ROI                   matlab.ui.control.Table
        AvoidPanel                    matlab.ui.container.Panel
        Button_AvoidDisplay           matlab.ui.control.Button
        Button_AvoidMinus             matlab.ui.control.Button
        Button_AvoidAdd               matlab.ui.control.Button
        UITable_Avoid                 matlab.ui.control.Table
        OptimalcoefficientsPanel      matlab.ui.container.Panel
        coefficient                   matlab.ui.container.GridLayout
        OptimalMethodDropDown         matlab.ui.control.DropDown
        OptimalMethodDropDownLabel    matlab.ui.control.Label
        electrodenumberDropDownLabel  matlab.ui.control.Label
        electrodenumberDropDown       matlab.ui.control.DropDown
        ConfigurationButton           matlab.ui.control.Button
        AvoidcoefEditField            matlab.ui.control.NumericEditField
        AvoidcoefEditFieldLabel       matlab.ui.control.Label
        OtherregionsalphaEditField    matlab.ui.control.NumericEditField
        OtherregionsalphaEditFieldLabel  matlab.ui.control.Label
        ROIsalphaEditField            matlab.ui.control.NumericEditField
        ROIsalphaEditFieldLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        OrientationPanel              matlab.ui.container.Panel
        OrientationLamp               matlab.ui.control.Lamp
        OrientationCheckBox           matlab.ui.control.CheckBox
        Panel_dataRoot                matlab.ui.container.Panel
        Grid_dataRoot                 matlab.ui.container.GridLayout
        Button_dataRoot               matlab.ui.control.Button
        EditField_dataRoot            matlab.ui.control.EditField
        Panel_subMark                 matlab.ui.container.Panel
        subMarkLamp                   matlab.ui.control.Lamp
        DropDown_subMark              matlab.ui.control.DropDown
        Panel_simMark                 matlab.ui.container.Panel
        EditField_simMark             matlab.ui.control.EditField
        ROIthreshold                  matlab.ui.container.ButtonGroup
        ROIthresEditField             matlab.ui.control.NumericEditField
        VmEditFieldLabel              matlab.ui.control.Label
        Panel_Plots                   matlab.ui.container.Panel
        Grid_Plots                    matlab.ui.container.GridLayout
        Panel_cfgImport               matlab.ui.container.Panel
        Grid_cfgImport                matlab.ui.container.GridLayout
        Button_cfgImport              matlab.ui.control.Button
        EditField_cfgImport           matlab.ui.control.EditField
        Image_Logo                    matlab.ui.control.Image
        UIAxes_figure                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        subList;
        %% display
        gmsTR;
        ROI_TR;
        Avoid_TR;
        %% Color
        ROIColor = [139,0,0];
        AvoidColor = [0,0,139];
        %%
        ROIview;
        hobj;
        targetNew;
        varTypes = ["string","double","double","string","double"];
        varNames = ["Name","CoordMNI","CoordSub","Shape","Radius"];
        targetUINew;
        cfgFile;
        %% status
        GrayMidSurface_status = false;
        TetLeadfield_status = false;
        Headreco_status = false;
        dataRoot_status = true;
        WMnt_status = false;
        GMnt_status = false;
        %% msg
        Headreco_error_msg = 'Headreco meshing in simNIBS failed';
        TetLeadfield_error_msg = 'The tetraheron leadfield is not available.';
        GrayMidSurface_error_msg = 'No Gray matter middle surface for display.';
        %%
        optimalMethodPool = {'Exhaustion','Genetic Algorithm'};
    end

    properties (Access = public)
        dataRoot = 'D:\simnibs_examples';
        subMark = '015';
        simDir;
        m2mPath;
        simMark;
        mshMeshFile;
        GMSFile;
        tetLFFile;
        WMntFile;
        GMntFile;
        cfg;
        row_ROI_table_selected;
        row_Avoid_table_selected;
    end

    methods (Access = public)
        function updateTarget(app,regionName,coordMNI,r,shapeType)
            coordSub = mni2subject_coords(coordMNI,app.m2mPath);
            app.targetNew = {regionName,coordMNI,coordSub,shapeType,r};
            app.targetUINew = {regionName,num2str(round(10*coordMNI)/10),num2str(round(10*coordSub)/10),shapeType,num2str(r)};
        end
    end

    methods (Access = private)

        function UpdateSublist(app)
            d = dir(app.dataRoot);
            f = {d([d.isdir]).name};
            app.subList = ['Undefined',f(3:end)]';
            app.DropDown_subMark.Items = app.subList;
        end

        function SubMarkDefault(app)
            %% subMark
            app.subMarkLamp.Color = [1,0,0];
            app.m2mPath = '';
            %% simMark
            app.simMark = '';
            app.EditField_simMark.Value = app.simMark;
            app.simDir = '';
            %% thres
            app.cfg.type = 'tet';
            app.cfg.thres = 0.2;
            app.ROIthresEditField.Value = app.cfg.thres;
            %% Orientation
            app.cfg.nt = false;
            app.OrientationCheckBox.Value = 0;
            app.WMnt_status = false;
            app.WMntFile = '';
            app.GMnt_status = false;
            app.GMntFile = '';
            app.OrientationLamp.Color = [1,0,0];
            %% ROI table
            app.cfg.ROI.num = 0;
            app.cfg.ROI.table = table('Size',[0,5],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.UITable_ROI.Data = [];
            %% Avoid table
            app.cfg.Avoid.num = 0;
            app.cfg.Avoid.table = table('Size',[0,5],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.UITable_ROI.Data = [];

            app.cfg.ROI.alpha = 2;
            app.ROIsalphaEditField.Value = app.cfg.ROI.alpha;

            app.cfg.Other.alpha = 2;
            app.OtherregionsalphaEditField.Value = app.cfg.Other.alpha;

            app.cfg.Avoid.coef = 1;
            app.AvoidcoefEditField.Value = app.cfg.Avoid.coef;

            app.cfg.elecNum = 4;
            app.electrodenumberDropDown.Value = app.electrodenumberDropDown.Items{1};

            app.OptimalMethodDropDown.Items = app.optimalMethodPool;
            app.cfg.optimalMethod = app.OptimalMethodDropDown.Items{1};
            app.OptimalMethodDropDown.Value = app.OptimalMethodDropDown.Items{1};
            %% headreco
            app.Headreco_status = false;
            app.mshMeshFile = '';
            %%　gray matter surface
            app.GrayMidSurface_status = false;
            app.GMSFile = '';
            %% tet leadfield
            app.TetLeadfield_status = false;
            app.tetLFFile = '';
            %%  TR
            app.gmsTR = [];
            app.ROI_TR = [];
            app.Avoid_TR = [];
            cla(app.UIAxes_figure);
            title(app.UIAxes_figure,app.subMark);
        end

        function ImportCfg(app)
            %% read
            S = load(app.cfgFile);
            app.cfg = S.cfg;
            %% dataRoot
            app.dataRoot = app.cfg.dataRoot;
            app.EditField_dataRoot.Value = app.dataRoot;
            UpdateSublist(app);
            %% subMark
            app.subMark = app.cfg.subMark;
            if ~ismember(app.subMark,app.subList)
                app.subMark = app.subList{1};
            end
            app.DropDown_subMark.Value = app.subMark;
            if strcmp(app.subMark,'Undefined')
                SubMarkDefault(app);
                msgbox('The import cfg has wrong subMark.');
                disp('The import cfg has wrong subMark.');
            else
                app.simMark = app.cfg.simMark;
                app.EditField_simMark.Value = app.simMark;
                SubMarkUpdate(app);
                %% update parameters
                if app.cfg.nt
                    app.OrientationCheckBox.Value = 1;
                end
                %% ROI
                if app.cfg.ROI.num>0
                    app.UITable_ROI.Data = [app.cfg.ROI.table.Name,...
                        num2str(app.cfg.ROI.table.CoordMNI),...
                        app.cfg.ROI.table.Shape,...;
                        num2str(app.cfg.ROI.table.Radius)];
                end
                %% Avoid
                if app.cfg.Avoid.num>0
                    app.UITable_Avoid.Data = [app.cfg.Avoid.table.Name,...
                        num2str(app.cfg.Avoid.table.CoordMNI),...
                        app.cfg.Avoid.table.Shape,...;
                        num2str(app.cfg.Avoid.table.Radius)];
                end
                %% other
                app.electrodenumberDropDown.Value = num2str(app.cfg.elecNum);
                app.OptimalMethodEditField.Value = app.cfg.elecCandNum;
                app.AvoidcoefEditField.Value = app.cfg.Avoid.coef;
                app.OtherregionsalphaEditField.Value = app.cfg.Other.alpha;
                app.ROIsalphaEditField.Value = app.cfg.ROI.alpha;
                app.ROIthresholdEditField.Value = app.cfg.thres;
                app.OptimalMethodDropDown.value = app.cfg.optimalMethod;
            end
        end

        function ShowGMS(app)
            m0 = mesh_load_hdf5(app.GMSFile);
            m1 = m0(2).mesh;
            gmS = mesh_extract_regions(m1, 'region_idx', [1 2]); % gray matter surface in both hemisphere
            app.gmsTR = triangulation(double(gmS.triangles),gmS.nodes);
            app.hobj = trimesh(app.gmsTR.ConnectivityList,app.gmsTR.Points(:,1),app.gmsTR.Points(:,2),app.gmsTR.Points(:,3),'EdgeColor','none',...
                'FaceColor', [130/255 130/255 130/255],'FaceAlpha',0.05,'Parent',app.UIAxes_figure);
            title(app.UIAxes_figure,app.subMark,'FontWeight','normal');
            lighting(app.UIAxes_figure,'gouraud');
            rotate3d(app.UIAxes_figure,'on');
            set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
            axis(app.UIAxes_figure,'off');
            material(app.hobj,'dull');
        end

        function CheckDataRoot(app)
            if ~isfolder(app.dataRoot)
                msgbox('Not correct root data directory!')
                app.dataRoot_status = false;
            else
                app.dataRoot_status = true;
            end
        end

        function SubMarkUpdate(app)
            disp(['For subject ' app.subMark ' :']);
            %% change path
            app.m2mPath = fullfile(app.dataRoot,app.subMark, ['m2m_' app.subMark]);
            %% headreco brain mesh
            app.Headreco_status = CheckHeadreco(app.dataRoot,app.subMark,1);
            if app.Headreco_status
                %% GMS
                [app.GrayMidSurface_status,app.GMSFile] = CheckGMS(app.dataRoot,app.subMark,1);
                if app.GrayMidSurface_status
                    ShowGMS(app);
                else
                    cla(app.UIAxes_figure);
                    app.gmsTR = [];
                    title(app.UIAxes_figure,app.GrayMidSurface_error_msg);
                    disp(app.GrayMidSurface_error_msg);
                end
                %% whole brain tetrahedron leadfield
                app.TetLeadfield_status = CheckTetLeadfield(app.dataRoot,app.subMark,1);
            else
                cla(app.UIAxes_figure);
                title(app.UIAxes_figure,[app.subMark ': ' app.Headreco_error_msg],'FontWeight','normal');
                app.subMarkLamp.Color = [1,0,0];
            end
            %% Lamp SubMark
            if (app.TetLeadfield_status&&app.Headreco_status)
                app.subMarkLamp.Color = [0,1,0];
            else
                disp(app.TetLeadfield_error_msg);
                app.subMarkLamp.Color = [1,0,0];
            end
            %% Lamp Orientation
            [app.GMnt_status,app.WMnt_status] = CheckOrientation(app.dataRoot,app.subMark,1);
            if(app.WMnt_status&&app.GMnt_status)
                app.OrientationLamp.Color = [0,1,0];
            else
                app.OrientationLamp.Color = [1,0,0];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% figure
            title(app.UIAxes_figure,'Gray matter brain figure','FontWeight','normal');
            lighting(app.UIAxes_figure,'gouraud');
            rotate3d(app.UIAxes_figure,'on');
            set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
            axis(app.UIAxes_figure,'off');
            material(app.hobj,'dull');
            %% dataRoot
            try
                app.dataRoot = evalin('base','dataRoot');
            catch
                disp('No dataRoot variable in base workspace.')
            end
            app.EditField_dataRoot.Value = app.dataRoot;
            if ~isempty(app.dataRoot)
                UpdateSublist(app);
            end
            %% subMark
            try
                app.subMark = evalin('base','subMark');
            catch
                disp('No subMark variable in base workspace.')
            end
            if ~ismember(app.subMark,app.subList)
                app.subMark = app.subList{1};
                disp('The subMark in base workspace is not under dataRoot. Please check.')
            end
            app.DropDown_subMark.Value = app.subMark;
            SubMarkDefault(app);
            if ~strcmp(app.subMark,'Undefined')
                SubMarkUpdate(app);
            end
            try
                app.simMark = evalin('base','simMark');
                app.EditField_simMark.Value = app.simMark;
            catch
                disp('No simMark variable in base workspace.')
            end
        end

        % Button pushed function: Button_dataRoot
        function Button_dataRootPushed(app, event)
            dataRoot_tmp = uigetdir(pwd, 'Data root directory');
            if dataRoot_tmp ~=0
                app.dataRoot = dataRoot_tmp;
                app.EditField_dataRoot.Value = app.dataRoot;
                UpdateSublist(app);
                app.DropDown_subMark.Value = app.subList{1};
            end
        end

        % Value changed function: EditField_dataRoot
        function EditField_dataRootValueChanged(app, event)
            value = app.EditField_dataRoot.Value;
            if ~isempty(value)
                app.dataRoot = value;
                app.EditField_dataRoot.Value = app.dataRoot;
                UpdateSublist(app);
                app.DropDown_subMark.Value = app.subList{1};
            end
        end

        % Value changed function: DropDown_subMark
        function DropDown_subMarkValueChanged(app, event)
            %% default
            app.subMark = app.DropDown_subMark.Value;
            if strcmp(app.subMark,'Undefined')
                SubMarkDefault(app);
            else
                SubMarkUpdate(app);
            end
        end

        % Value changed function: EditField_simMark
        function EditField_simMarkValueChanged(app, event)
            app.simMark = app.EditField_simMark.Value;
            app.simDir = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
            if(isfolder(app.simDir))
                msgbox('This simMark has been used, the old data would be overwritten!')
            end
        end

        % Button pushed function: Button_ROIAdd
        function Button_ROIAddPushed(app, event)
            app.targetNew = [];
            app.targetUINew = [];
            appTarget = Target_GUI(app);
            uiwait(appTarget.UI_DialogTarget);
            %%
            if ~isempty(app.targetNew)
                app.cfg.ROI.num = app.cfg.ROI.num + 1;
                app.cfg.ROI.table = [app.cfg.ROI.table; app.targetNew];
                app.UITable_ROI.Data = [app.UITable_ROI.Data; app.targetUINew];
            end
            disp('ROI table:');
            disp(app.cfg.ROI.table);
        end

        % Button pushed function: Button_AvoidAdd
        function Button_AvoidAddPushed(app, event)
            app.targetNew = [];
            app.targetUINew = [];
            appTarget = Target_GUI(app);
            uiwait(appTarget.UI_DialogTarget);
            %%
            if ~isempty(app.targetNew)
                app.cfg.Avoid.num = app.cfg.Avoid.num + 1;
                app.cfg.Avoid.table = [app.cfg.Avoid.table; app.targetNew];
                app.UITable_Avoid.Data = [app.UITable_Avoid.Data; app.targetUINew];
            end
            disp('Avoid table:');
            disp(app.cfg.Avoid.table);
        end

        % Cell selection callback: UITable_ROI
        function UITable_ROICellSelection(app, event)
            if ~isempty(event.Indices)
                app.row_ROI_table_selected = event.Indices(1);
            end
        end

        % Cell selection callback: UITable_Avoid
        function UITable_AvoidCellSelection(app, event)
            if ~isempty(event.Indices)
                app.row_Avoid_table_selected = event.Indices(1);
            end
        end

        % Button pushed function: Button_ROIMinus
        function Button_ROIMinusPushed(app, event)
            if app.row_ROI_table_selected>0
                app.UITable_ROI.Data(app.row_ROI_table_selected,:) = [];
                app.cfg.ROI.num = app.cfg.ROI.num - 1;
                app.cfg.ROI.table(app.row_ROI_table_selected,:) = [];
                disp('ROI table:');
                disp(app.cfg.ROI.table);
            end
        end

        % Button pushed function: Button_AvoidMinus
        function Button_AvoidMinusPushed(app, event)
            if app.row_Avoid_table_selected>0
                app.UITable_Avoid.Data(app.row_Avoid_table_selected,:) = [];
                app.cfg.Avoid.num = app.cfg.Avoid.num - 1;
                app.cfg.Avoid.table(app.row_Avoid_table_selected,:) = [];
                disp('Avoid table:');
                disp(app.cfg.Avoid.table);
            end
        end

        % Button pushed function: Button_ROIDisplay
        function Button_ROIDisplayPushed(app, event)
            [app.ROIview.az,app.ROIview.el]=view(app.UIAxes_figure);
            hold(app.UIAxes_figure,'off');
            cla(app.UIAxes_figure);
            if app.GrayMidSurface_status
                ShowGMS(app);
                %% plot
                view(app.UIAxes_figure,app.ROIview.az,app.ROIview.el);
                hold(app.UIAxes_figure,'on');
                rotate3d(app.UIAxes_figure,'on');
                set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
                axis(app.UIAxes_figure,'off');
                if app.cfg.ROI.num>0
                    for i = 1 : app.cfg.ROI.num
                        tmp = app.cfg.ROI.table(i,:);
                        switch tmp.Shape
                            case 'Sphere'
                                [x,y,z] = sphere(32);
                                x = x*tmp.Radius+tmp.CoordSub(1);
                                y = y*tmp.Radius+tmp.CoordSub(2);
                                z = z*tmp.Radius+tmp.CoordSub(3);
                                fvc = surf2patch(x,y,z,'triangles');
                                app.ROI_TR = triangulation(fvc.faces,fvc.vertices);
                            case 'AAL3'
                                [~,ROI_TR_MNI] = AAL3ROI(tmp.Name);
                                node_sub = mni2subject_coords(ROI_TR_MNI.Points, app.m2mPath); % transform to subject space
                                app.ROI_TR = triangulation(ROI_TR_MNI.ConnectivityList,node_sub);
                        end
                        trimesh(app.ROI_TR.ConnectivityList,app.ROI_TR.Points(:,1),app.ROI_TR.Points(:,2),app.ROI_TR.Points(:,3),'EdgeColor','none',...
                            'FaceColor', app.ROIColor/255,'FaceAlpha',1,'Parent',app.UIAxes_figure);
                    end
                end
            end
        end

        % Button pushed function: Button_AvoidDisplay
        function Button_AvoidDisplayPushed(app, event)
            if app.GrayMidSurface_status
                hold(app.UIAxes_figure,'on');
                if app.cfg.Avoid.num>0
                    for i = 1 : app.cfg.Avoid.num
                        tmp = app.cfg.Avoid.table(i,:);
                        switch tmp.Shape
                            case 'Sphere'
                                [x,y,z] = sphere(32);
                                x = x*tmp.Radius+tmp.CoordSub(1);
                                y = y*tmp.Radius+tmp.CoordSub(2);
                                z = z*tmp.Radius+tmp.CoordSub(3);
                                fvc = surf2patch(x,y,z,'triangles');
                                app.Avoid_TR = triangulation(fvc.faces,fvc.vertices);
                            case 'AAL3'
                                [~,Avoid_TR_MNI] = AAL3ROI(tmp.Name);
                                node_sub = mni2subject_coords(Avoid_TR_MNI.Points, app.m2mPath); % transform to subject space
                                app.Avoid_TR = triangulation(Avoid_TR_MNI.ConnectivityList,node_sub);
                        end
                        trimesh(app.Avoid_TR.ConnectivityList,app.Avoid_TR.Points(:,1),app.Avoid_TR.Points(:,2),app.Avoid_TR.Points(:,3),'EdgeColor','none',...
                            'FaceColor', app.AvoidColor/255,'FaceAlpha',1,'Parent',app.UIAxes_figure);
                    end
                end
            end
        end

        % Value changed function: OrientationCheckBox
        function OrientationCheckBoxValueChanged(app, event)
            app.cfg.nt = app.OrientationCheckBox.Value;
            if app.cfg.nt
                CheckOrientation(app,1);
                if (app.WMnt_status&&app.GMnt_status)
                    app.OrientationLamp.Color = [0,1,0];
                    app.OrientationCheckBox.Value = true;
                else
                    app.OrientationLamp.Color = [1,0,0];
                    app.cfg.nt = false;
                    app.OrientationCheckBox.Value = false;
                end
            end
        end

        % Value changed function: electrodenumberDropDown
        function electrodenumberDropDownValueChanged(app, event)
            app.cfg.elecNum = app.electrodenumberDropDown.Value;
            if str2double(app.cfg.elecNum)>4
                app.OptimalMethodDropDown.Items = app.optimalMethodPool(2);
                app.OptimalMethodDropDown.Value = app.OptimalMethodDropDown.Items{1};
                app.cfg.optimalMethod = app.OptimalMethodDropDown.Value;
            else
                app.OptimalMethodDropDown.Items = app.optimalMethodPool;
                app.OptimalMethodDropDown.Value = app.OptimalMethodDropDown.Items{1};
                app.cfg.optimalMethod = app.OptimalMethodDropDown.Value;
            end
        end

        % Button pushed function: ConfigurationButton
        function ConfigurationButtonPushed(app, event)
            %% save
            if isempty(app.simMark)
                msgbox('The simMark not allowd empty!','Error');
                status = false;
            else
                app.simDir = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
                if exist(app.simDir,'dir')
                    answer = questdlg('Would you like to overwrite the data under this simMark?', ...
                        'Warning','Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            status = true;
                        case 'No'
                            status = false;
                    end
                else
                    mkdir(app.simDir);
                    status = true;
                end
            end
            disp(['For subject ' app.subMark ' :']);
            disp(['OutputPath is ' app.simDir]);
            disp('Final check:')
            if status
                %% Check
                app.Headreco_status = CheckHeadreco(app.dataRoot,app.subMark,2);
                if app.Headreco_status
                    app.GrayMidSurface_status = CheckGMS(app.dataRoot,app.subMark,2);
                    app.TetLeadfield_status = CheckTetLeadfield(app.dataRoot,app.subMark,2);
                end
                if app.cfg.nt
                    [app.GMnt_status,app.WMnt_status] = CheckOrientation(app.dataRoot,app.subMark,2);
                else
                    disp('No need for orientation file.');
                end
                %% save cfg and pass it to the base workspace
                app.cfg.dataRoot = app.dataRoot;
                app.cfg.subMark = app.subMark;
                app.cfg.simMark = app.simMark;
                cfg = app.cfg;
                app.cfgFile = fullfile(app.simDir,'cfg.mat');
                save(app.cfgFile,'cfg');
                assignin('base','cfg',cfg);
                disp('Please eval "OpenTIPS" in main prompt command line.')
                delete(app);
            end

        end

        % Value changed function: AvoidcoefEditField
        function AvoidcoefEditFieldValueChanged(app, event)
            app.cfg.Avoid.coef = app.AvoidcoefEditField.Value;
        end

        % Value changed function: OtherregionsalphaEditField
        function OtherregionsalphaEditFieldValueChanged(app, event)
            app.cfg.Other.alpha = app.OtherregionsalphaEditField.Value;
        end

        % Value changed function: ROIsalphaEditField
        function ROIsalphaEditFieldValueChanged(app, event)
            app.cfg.ROI.alpha = app.ROIsalphaEditField.Value;
        end

        % Callback function
        function ROIthresholdEditFieldValueChanged(app, event)
            app.cfg.thres = app.ROIthresholdEditField.Value;
        end

        % Button pushed function: Button_cfgImport
        function Button_cfgImportPushed(app, event)
            if ~isempty(app.dataRoot)
                if ~strcmp('Undefined',app.DropDown_subMark.Value)
                    defaultDir = fullfile(app.dataRoot,app.subMark);
                else
                    defaultDir = app.dataRoot;
                end
            else
                defaultDir = pwd;
            end
            [file,dir] = uigetfile(defaultDir, 'Import existed configuration file');
            app.cfgFile = fullfile(dir,file);
            app.EditField_cfgImport.Value = app.cfgFile;
            if app.cfgFile ~=0
                ImportCfg(app);
            end
        end

        % Value changed function: EditField_cfgImport
        function EditField_cfgImportValueChanged(app, event)
            app.cfgFile = app.EditField_cfgImport.Value;
            ImportCfg(app);
        end

        % Value changed function: ROIthresEditField
        function ROIthresEditFieldValueChanged(app, event)
            app.cfg.thres = app.ROIthresEditField.Value;
        end

        % Value changed function: OptimalMethodDropDown
        function OptimalMethodDropDownValueChanged(app, event)
            value = app.OptimalMethodDropDown.Value;
            app.cfg.optimalMethod = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UI_OpenTIPS.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {663, 663};
                app.GridLayout.ColumnWidth = {'1x'};
                app.Panel_Plots.Layout.Row = 2;
                app.Panel_Plots.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {450, '1x'};
                app.Panel_Plots.Layout.Row = 1;
                app.Panel_Plots.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_OpenTIPS = uifigure('Visible', 'off');
            app.UI_OpenTIPS.Name = 'OpenTIPS';
            app.UI_OpenTIPS.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UI_OpenTIPS);
            app.Panel_Settings = uipanel(app.GridLayout);
            app.Grid_Settings = uigridlayout(app.Panel_Settings);
            app.ROIthreshold = uibuttongroup(app.Grid_Settings);
            app.ROIthreshold.Title = 'ROI threshold';
            app.VmEditFieldLabel = uilabel(app.ROIthreshold);
            app.VmEditFieldLabel.Text = 'V/m';
            app.ROIthresEditField = uieditfield(app.ROIthreshold, 'numeric');
            app.ROIthresEditField.ValueChangedFcn = createCallbackFcn(app, @ROIthresEditFieldValueChanged, true);
            app.ROIthresEditField.Value = 0.2;
            app.Panel_simMark = uipanel(app.Grid_Settings);
            app.Panel_simMark.Title = 'simMark';
            app.EditField_simMark = uieditfield(app.Panel_simMark, 'text');
            app.EditField_simMark.ValueChangedFcn = createCallbackFcn(app, @EditField_simMarkValueChanged, true);
            app.Panel_subMark = uipanel(app.Grid_Settings);
            app.Panel_subMark.Title = 'Subject';
            app.DropDown_subMark = uidropdown(app.Panel_subMark);
            app.DropDown_subMark.Items = {'Undefined'};
            app.DropDown_subMark.ValueChangedFcn = createCallbackFcn(app, @DropDown_subMarkValueChanged, true);
            app.DropDown_subMark.Value = 'Undefined';
            app.subMarkLamp = uilamp(app.Panel_subMark);
            app.Panel_dataRoot = uipanel(app.Grid_Settings);
            app.Panel_dataRoot.Title = 'Data root';
            app.Grid_dataRoot = uigridlayout(app.Panel_dataRoot);
            app.EditField_dataRoot = uieditfield(app.Grid_dataRoot, 'text');
            app.EditField_dataRoot.ValueChangedFcn = createCallbackFcn(app, @EditField_dataRootValueChanged, true);
            app.Button_dataRoot = uibutton(app.Grid_dataRoot, 'push');
            app.Button_dataRoot.ButtonPushedFcn = createCallbackFcn(app, @Button_dataRootPushed, true);
            app.Button_dataRoot.Text = '...';
            app.OrientationPanel = uipanel(app.Grid_Settings);
            app.OrientationPanel.Title = 'Orientation';
            app.OrientationCheckBox = uicheckbox(app.OrientationPanel);
            app.OrientationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientationCheckBoxValueChanged, true);
            app.OrientationCheckBox.Text = 'gray and white matter';
            app.OrientationLamp = uilamp(app.OrientationPanel);
            app.Panel = uipanel(app.Grid_Settings);
            app.OptimalcoefficientsPanel = uipanel(app.Grid_Settings);
            app.OptimalcoefficientsPanel.Title = 'Optimal coefficients';
            app.coefficient = uigridlayout(app.OptimalcoefficientsPanel);
            app.ROIsalphaEditFieldLabel = uilabel(app.coefficient);
            app.ROIsalphaEditFieldLabel.Text = 'ROI''s alpha';
            app.ROIsalphaEditField = uieditfield(app.coefficient, 'numeric');
            app.ROIsalphaEditField.ValueChangedFcn = createCallbackFcn(app, @ROIsalphaEditFieldValueChanged, true);
            app.ROIsalphaEditField.Value = Inf;
            app.OtherregionsalphaEditFieldLabel = uilabel(app.coefficient);
            app.OtherregionsalphaEditFieldLabel.Text = 'Other region''s alpha';
            app.OtherregionsalphaEditField = uieditfield(app.coefficient, 'numeric');
            app.OtherregionsalphaEditField.ValueChangedFcn = createCallbackFcn(app, @OtherregionsalphaEditFieldValueChanged, true);
            app.OtherregionsalphaEditField.Value = 2;
            app.AvoidcoefEditFieldLabel = uilabel(app.coefficient);
            app.AvoidcoefEditFieldLabel.Text = 'Avoid coef';
            app.AvoidcoefEditField = uieditfield(app.coefficient, 'numeric');
            app.AvoidcoefEditField.ValueChangedFcn = createCallbackFcn(app, @AvoidcoefEditFieldValueChanged, true);
            app.AvoidcoefEditField.Value = 2;
            app.ConfigurationButton = uibutton(app.coefficient, 'push');
            app.ConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationButtonPushed, true);
            app.ConfigurationButton.Text = 'Configuration';
            app.electrodenumberDropDown = uidropdown(app.coefficient);
            app.electrodenumberDropDown.Items = {'4', '8'};
            app.electrodenumberDropDown.ValueChangedFcn = createCallbackFcn(app, @electrodenumberDropDownValueChanged, true);
            app.electrodenumberDropDown.Value = '4';
            app.electrodenumberDropDownLabel = uilabel(app.coefficient);
            app.electrodenumberDropDownLabel.Text = {'electrode number'; ''};
            app.OptimalMethodDropDownLabel = uilabel(app.coefficient);
            app.OptimalMethodDropDownLabel.Text = 'Optimal Method';
            app.OptimalMethodDropDown = uidropdown(app.coefficient);
            app.OptimalMethodDropDown.Items = {'Exhaustion', 'Genetic Algorithm'};
            app.OptimalMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimalMethodDropDownValueChanged, true);
            app.OptimalMethodDropDown.Value = 'Exhaustion';
            app.AvoidPanel = uipanel(app.Grid_Settings);
            app.AvoidPanel.Title = 'Avoid region setting';
            app.UITable_Avoid = uitable(app.AvoidPanel);
            app.UITable_Avoid.ColumnName = {'Name'; 'MNI_XYZ'; 'Sub_XYZ'; 'Shape'; 'Radius'};
            app.UITable_Avoid.RowName = {'1'};
            app.UITable_Avoid.CellSelectionCallback = createCallbackFcn(app, @UITable_AvoidCellSelection, true);
            app.Button_AvoidAdd = uibutton(app.AvoidPanel, 'push');
            app.Button_AvoidAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_AvoidAddPushed, true);
            app.Button_AvoidAdd.Text = '+';
            app.Button_AvoidMinus = uibutton(app.AvoidPanel, 'push');
            app.Button_AvoidMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_AvoidMinusPushed, true);
            app.Button_AvoidMinus.Text = '-';
            app.Button_AvoidDisplay = uibutton(app.AvoidPanel, 'push');
            app.Button_AvoidDisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_AvoidDisplayPushed, true);
            app.Button_AvoidDisplay.Text = 'D';
            app.ROIsettingPanel = uipanel(app.Grid_Settings);
            app.ROIsettingPanel.Title = 'ROI setting';
            app.UITable_ROI = uitable(app.ROIsettingPanel);
            app.UITable_ROI.ColumnName = {'Name'; 'MNI_XYZ'; 'Sub_XYZ'; 'Shape'; 'Radius'};
            app.UITable_ROI.RowName = {'1'};
            app.UITable_ROI.CellSelectionCallback = createCallbackFcn(app, @UITable_ROICellSelection, true);
            app.Button_ROIAdd = uibutton(app.ROIsettingPanel, 'push');
            app.Button_ROIAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIAddPushed, true);
            app.Button_ROIAdd.Text = '+';
            app.Button_ROIMinus = uibutton(app.ROIsettingPanel, 'push');
            app.Button_ROIMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIMinusPushed, true);
            app.Button_ROIMinus.Text = '-';
            app.Button_ROIDisplay = uibutton(app.ROIsettingPanel, 'push');
            app.Button_ROIDisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIDisplayPushed, true);
            app.Button_ROIDisplay.Text = 'D';
            app.Panel_Plots = uipanel(app.GridLayout);
            app.Grid_Plots = uigridlayout(app.Panel_Plots);
            app.UIAxes_figure = uiaxes(app.Grid_Plots);
            title(app.UIAxes_figure, 'Title')
            xlabel(app.UIAxes_figure, 'X')
            ylabel(app.UIAxes_figure, 'Y')
            app.Image_Logo = uiimage(app.Grid_Plots);
            app.Image_Logo.ImageSource = 'opentips.png';
            app.Panel_cfgImport = uipanel(app.Grid_Plots);
            app.Panel_cfgImport.Title = 'Import configuration';
            app.Grid_cfgImport = uigridlayout(app.Panel_cfgImport);
            app.EditField_cfgImport = uieditfield(app.Grid_cfgImport, 'text');
            app.EditField_cfgImport.ValueChangedFcn = createCallbackFcn(app, @EditField_cfgImportValueChanged, true);
            app.Button_cfgImport = uibutton(app.Grid_cfgImport, 'push');
            app.Button_cfgImport.ButtonPushedFcn = createCallbackFcn(app, @Button_cfgImportPushed, true);
            app.Button_cfgImport.Text = '...';
            % Show the figure after all components are created
            app.UI_OpenTIPS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIPSconfig_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_OpenTIPS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_OpenTIPS)
        end
    end
end